classdef ColorLamp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ColorChangingLampLabel  matlab.ui.control.Label
        SendColorButton         matlab.ui.control.StateButton
        UIAxes                  matlab.ui.control.UIAxes
        AdjustColorPanel        matlab.ui.container.Panel
        RedSliderLabel          matlab.ui.control.Label
        RedSlider               matlab.ui.control.Slider
        GreenSliderLabel        matlab.ui.control.Label
        GreenSlider             matlab.ui.control.Slider
        BlueSliderLabel         matlab.ui.control.Label
        BlueSlider              matlab.ui.control.Slider
    end


    properties (Access = private)
        Red          % Store color values 
        Green
        Blue
    end

    methods (Access = private)
    
        function sendColor(app)
            % Update the color on the axes in the app
            updateColorPlot(app)
            
            % Update the color of the lamp 
            % % TODO: fill in api key w/ input args
%             colorURL = "url";
%             postName1="access_token";
%             postVal1 = "token"
%             postName2="arg";
            % Create color string from RGB values to look like:
            % postVal2 = "RRRGGGBBB";
            r = padZeros(app,app.Red);
            g = padZeros(app,app.Green);
            b = padZeros(app,app.Blue);
            postVal2 = r + g + b;
            % write and change color
            webwrite(colorURL,postName1,postVal1,postName2,postVal2);
        
        end
        

    end

    methods (Access = public)
        
        function s = padZeros(app,num)
            % Use strings to add zeros to beginning of number and take last 3
            pad = "000000" + string(num);
            pad = char(pad);
            s = pad(end-2:end);
            s = string(s);
            
            end

        function updateColorPlot(app)
            r = app.Red/255;
            g = app.Green/255;
            b = app.Blue/255;
            app.UIAxes.Color = [r g b];
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Red = 0;
            app.Blue = 0;
            app.Green = 0;
            updateColorPlot(app)
        end

        % Value changed function: SendColorButton
        function SendColorButtonValueChanged(app, event)
            sendColor(app)
        end

        % Value changed function: RedSlider
        function RedSliderValueChanged(app, event)
            value = app.RedSlider.Value;
            app.Red = round(value);
            sendColor(app)

        end

        % Value changed function: GreenSlider
        function GreenSliderValueChanged(app, event)
            value = app.GreenSlider.Value;
            app.Green = round(value);
            sendColor(app)
        end

        % Value changed function: BlueSlider
        function BlueSliderValueChanged(app, event)
            value = app.BlueSlider.Value;
            app.Blue = round(value);
            sendColor(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ColorChangingLampLabel = uilabel(app.UIFigure);
            app.ColorChangingLampLabel.Text = 'Color Changing Lamp';
            app.SendColorButton = uibutton(app.UIFigure, 'state');
            app.SendColorButton.ValueChangedFcn = createCallbackFcn(app, @SendColorButtonValueChanged, true);
            app.SendColorButton.Text = 'Send Color';
            app.UIAxes = uiaxes(app.UIFigure);
            app.AdjustColorPanel = uipanel(app.UIFigure);
            app.AdjustColorPanel.Title = 'Adjust Color';
            app.RedSliderLabel = uilabel(app.AdjustColorPanel);
            app.RedSliderLabel.Text = 'Red';
            app.RedSlider = uislider(app.AdjustColorPanel);
            app.RedSlider.Limits = [0 255];
            app.RedSlider.ValueChangedFcn = createCallbackFcn(app, @RedSliderValueChanged, true);
            app.GreenSliderLabel = uilabel(app.AdjustColorPanel);
            app.GreenSliderLabel.Text = 'Green';
            app.GreenSlider = uislider(app.AdjustColorPanel);
            app.GreenSlider.Limits = [0 255];
            app.GreenSlider.ValueChangedFcn = createCallbackFcn(app, @GreenSliderValueChanged, true);
            app.BlueSliderLabel = uilabel(app.AdjustColorPanel);
            app.BlueSliderLabel.Text = 'Blue';
            app.BlueSlider = uislider(app.AdjustColorPanel);
            app.BlueSlider.Limits = [0 255];
            app.BlueSlider.ValueChangedFcn = createCallbackFcn(app, @BlueSliderValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ColorLamp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end